classdef liild < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        liildUIFigure             matlab.ui.Figure
        TabGroup                  matlab.ui.container.TabGroup
        inputWaves                matlab.ui.container.Tab
        earthquakePanel           matlab.ui.container.Panel
        sPGAEditField             matlab.ui.control.NumericEditField
        sLabel_5                  matlab.ui.control.Label
        degreeDropDown            matlab.ui.control.DropDown
        Label_24                  matlab.ui.control.Label
        groupDropDown             matlab.ui.control.DropDown
        Label_23                  matlab.ui.control.Label
        fieldDropDown             matlab.ui.control.DropDown
        Label_22                  matlab.ui.control.Label
        gPGAEditField             matlab.ui.control.NumericEditField
        sLabel_4                  matlab.ui.control.Label
        mPGAEditField             matlab.ui.control.NumericEditField
        sLabel_3                  matlab.ui.control.Label
        eqTgEditField             matlab.ui.control.NumericEditField
        sLabel_2                  matlab.ui.control.Label
        standardPanel             matlab.ui.container.Panel
        accField                  matlab.ui.control.NumericEditField
        PGAgLabel                 matlab.ui.control.Label
        smgDropDown               matlab.ui.control.DropDown
        Label_25                  matlab.ui.control.Label
        zetaField                 matlab.ui.control.NumericEditField
        Label_2                   matlab.ui.control.Label
        tgField                   matlab.ui.control.NumericEditField
        sEditFieldLabel           matlab.ui.control.Label
        plotStdRSA                matlab.ui.control.Button
        structPanel_2             matlab.ui.container.Panel
        TyEditField_2             matlab.ui.control.NumericEditField
        TssLabel                  matlab.ui.control.Label
        TvEditField_2             matlab.ui.control.NumericEditField
        TvsLabel_8                matlab.ui.control.Label
        ThEditField_2             matlab.ui.control.NumericEditField
        ThsLabel                  matlab.ui.control.Label
        m2tEditField_2            matlab.ui.control.NumericEditField
        m2tEditField_2Label       matlab.ui.control.Label
        m1tEditField_2            matlab.ui.control.NumericEditField
        m1tEditField_2Label       matlab.ui.control.Label
        inputPanel                matlab.ui.container.Panel
        GridLayout                matlab.ui.container.GridLayout
        deleteWavesButton         matlab.ui.control.Button
        dtField                   matlab.ui.control.NumericEditField
        sEditFieldLabel_2         matlab.ui.control.Label
        chosenWavesList           matlab.ui.control.ListBox
        Label                     matlab.ui.control.Label
        passWavesButton           matlab.ui.control.Button
        clearWavesButton          matlab.ui.control.Button
        openWavesButton           matlab.ui.control.Button
        RSAplot                   matlab.ui.control.UIAxes
        horiIsoDesign             matlab.ui.container.Tab
        horiClearButton           matlab.ui.control.Button
        horiDeleteButton          matlab.ui.control.Button
        horiComputeBetaButton     matlab.ui.control.Button
        horiSupportZetaEditField  matlab.ui.control.NumericEditField
        Label_27                  matlab.ui.control.Label
        horimbEditField           matlab.ui.control.NumericEditField
        CNmmsLabel                matlab.ui.control.Label
        horiSupportChosenListBox  matlab.ui.control.ListBox
        supportTabGroup           matlab.ui.container.TabGroup
        rubberTab                 matlab.ui.container.Tab
        rubberIntPanel            matlab.ui.container.Panel
        rubberKpEditField         matlab.ui.control.NumericEditField
        KpNmmLabel_4              matlab.ui.control.Label
        rubberKeEditField         matlab.ui.control.NumericEditField
        KeNmmEditFieldLabel_2     matlab.ui.control.Label
        rubberXyEditField         matlab.ui.control.NumericEditField
        XymmLabel_3               matlab.ui.control.Label
        rubberChosenButton        matlab.ui.control.Button
        rubberLimDispEditField    matlab.ui.control.NumericEditField
        tmmLabel_2                matlab.ui.control.Label
        LRBQdEditField            matlab.ui.control.NumericEditField
        QdNmmLabel                matlab.ui.control.Label
        LRBKpEditField            matlab.ui.control.NumericEditField
        KpNmmLabel                matlab.ui.control.Label
        LRBKeEditField            matlab.ui.control.NumericEditField
        KeNmmLabel_2              matlab.ui.control.Label
        LNRKhEditField            matlab.ui.control.NumericEditField
        KhNmmLabel                matlab.ui.control.Label
        LRBNumEditField           matlab.ui.control.NumericEditField
        Label_20                  matlab.ui.control.Label
        LNRNumEditField           matlab.ui.control.NumericEditField
        Label_19                  matlab.ui.control.Label
        rubberDDropDown           matlab.ui.control.DropDown
        DmmLabel                  matlab.ui.control.Label
        rubberGDropDown           matlab.ui.control.DropDown
        GLabel                    matlab.ui.control.Label
        rubberS2DropDown          matlab.ui.control.DropDown
        S2Label                   matlab.ui.control.Label
        frictionTab               matlab.ui.container.Tab
        friChosenButton           matlab.ui.control.Button
        friIntPanel               matlab.ui.container.Panel
        friTotalKeEditField       matlab.ui.control.NumericEditField
        KeNmmEditFieldLabel_3     matlab.ui.control.Label
        friTotalKpEditField       matlab.ui.control.NumericEditField
        KpNmmLabel_5              matlab.ui.control.Label
        friTotalXyEditField       matlab.ui.control.NumericEditField
        XymmLabel_4               matlab.ui.control.Label
        friPEditField             matlab.ui.control.NumericEditField
        PkNLabel                  matlab.ui.control.Label
        friREditField             matlab.ui.control.NumericEditField
        RmmLabel                  matlab.ui.control.Label
        friNumEditField           matlab.ui.control.NumericEditField
        Label_31                  matlab.ui.control.Label
        friMuEditField            matlab.ui.control.NumericEditField
        Label_30                  matlab.ui.control.Label
        friLimDispDropDown        matlab.ui.control.DropDown
        Label_29                  matlab.ui.control.Label
        friRockTDropDown          matlab.ui.control.DropDown
        Label_28                  matlab.ui.control.Label
        friVertForceDropDown      matlab.ui.control.DropDown
        kNLabel                   matlab.ui.control.Label
        friSigXyEditField         matlab.ui.control.NumericEditField
        XymmLabel_2               matlab.ui.control.Label
        friSigKpEditField         matlab.ui.control.NumericEditField
        KpNmmLabel_3              matlab.ui.control.Label
        friSigKeEditField         matlab.ui.control.NumericEditField
        KeNmmEditFieldLabel       matlab.ui.control.Label
        horiIsoLayerPanel         matlab.ui.container.Panel
        horiIsoZetaEditField      matlab.ui.control.NumericEditField
        zetaLabel                 matlab.ui.control.Label
        horiIsoKeqEditField       matlab.ui.control.NumericEditField
        KeqNmmLabel               matlab.ui.control.Label
        horiBetaEditField         matlab.ui.control.NumericEditField
        alphaLabel                matlab.ui.control.Label
        horiIsoXyEditField        matlab.ui.control.NumericEditField
        XymmLabel                 matlab.ui.control.Label
        horiIsoKpEditField        matlab.ui.control.NumericEditField
        KpNmmLabel_2              matlab.ui.control.Label
        horiIsoKeEditField        matlab.ui.control.NumericEditField
        KeNmmLabel_3              matlab.ui.control.Label
        confirmTv                 matlab.ui.container.Tab
        vZetaEditField            matlab.ui.control.NumericEditField
        Label_33                  matlab.ui.control.Label
        vBetaEditField            matlab.ui.control.NumericEditField
        Label_32                  matlab.ui.control.Label
        T0EditField               matlab.ui.control.NumericEditField
        Label_15                  matlab.ui.control.Label
        deformPanel               matlab.ui.container.Panel
        deformTT0EditField        matlab.ui.control.NumericEditField
        Label_13                  matlab.ui.control.Label
        deformEditField           matlab.ui.control.NumericEditField
        Label_12                  matlab.ui.control.Label
        stressPanel               matlab.ui.container.Panel
        stressTT0EditField        matlab.ui.control.NumericEditField
        Label_9                   matlab.ui.control.Label
        stressEditField           matlab.ui.control.NumericEditField
        Label_8                   matlab.ui.control.Label
        supportPanel              matlab.ui.container.Panel
        supportTT0EditField       matlab.ui.control.NumericEditField
        Label_11                  matlab.ui.control.Label
        supportEditField          matlab.ui.control.NumericEditField
        Label_10                  matlab.ui.control.Label
        targetTT0EditField        matlab.ui.control.NumericEditField
        Label_14                  matlab.ui.control.Label
        deformRateAxes            matlab.ui.control.UIAxes
        supportRateAxes           matlab.ui.control.UIAxes
        stressRateAxes            matlab.ui.control.UIAxes
        responseCompute           matlab.ui.container.Tab
        horiLimitEditField        matlab.ui.control.NumericEditField
        Label_21                  matlab.ui.control.Label
        resultPanel               matlab.ui.container.Panel
        vertEditField             matlab.ui.control.NumericEditField
        mmLabel_2                 matlab.ui.control.Label
        horiEditField             matlab.ui.control.NumericEditField
        mmLabel                   matlab.ui.control.Label
        solvePanel                matlab.ui.container.Panel
        computeMaxButton          matlab.ui.control.Button
        PGAEditField              matlab.ui.control.NumericEditField
        PGAgEditFieldLabel        matlab.ui.control.Label
        stepEditField             matlab.ui.control.NumericEditField
        sEditFieldLabel_4         matlab.ui.control.Label
        vertPanel                 matlab.ui.container.Panel
        vertZetaEditField         matlab.ui.control.NumericEditField
        Label_26                  matlab.ui.control.Label
        TT0EditField              matlab.ui.control.NumericEditField
        TvsLabel_6                matlab.ui.control.Label
        horiPanel                 matlab.ui.container.Panel
        KpEditField               matlab.ui.control.NumericEditField
        TvsLabel_3                matlab.ui.control.Label
        alphaEditField            matlab.ui.control.NumericEditField
        TvsLabel_7                matlab.ui.control.Label
        CEditField                matlab.ui.control.NumericEditField
        TvsLabel_5                matlab.ui.control.Label
        XyEditField               matlab.ui.control.NumericEditField
        TvsLabel_4                matlab.ui.control.Label
        KeEditField               matlab.ui.control.NumericEditField
        TvsLabel_2                matlab.ui.control.Label
        structPanel               matlab.ui.container.Panel
        TyEditField               matlab.ui.control.NumericEditField
        TssLabel_2                matlab.ui.control.Label
        TvEditField               matlab.ui.control.NumericEditField
        TvsLabel                  matlab.ui.control.Label
        ThEditField               matlab.ui.control.NumericEditField
        TfsLabel                  matlab.ui.control.Label
        m2tEditField              matlab.ui.control.NumericEditField
        m2tEditFieldLabel         matlab.ui.control.Label
        m1tEditField              matlab.ui.control.NumericEditField
        m1tEditFieldLabel         matlab.ui.control.Label
        inputPanel_RC             matlab.ui.container.Panel
        GridLayout2               matlab.ui.container.GridLayout
        deleteWavesButton_RC      matlab.ui.control.Button
        dtField_RC                matlab.ui.control.NumericEditField
        sLabel                    matlab.ui.control.Label
        chosenWavesList_RC        matlab.ui.control.ListBox
        Label_3                   matlab.ui.control.Label
        passWavesButton_RC        matlab.ui.control.Button
        clearWavesButton_RC       matlab.ui.control.Button
        openWavesButton_RC        matlab.ui.control.Button
        Image                     matlab.ui.control.Image
        horiAxes                  matlab.ui.control.UIAxes
        vertAxes                  matlab.ui.control.UIAxes
        vertSupportDesign         matlab.ui.container.Tab
        optButtonGroup            matlab.ui.container.ButtonGroup
        optH0Button               matlab.ui.control.RadioButton
        designButton              matlab.ui.control.Button
        resultPanel_SD            matlab.ui.container.Panel
        H0EditField               matlab.ui.control.NumericEditField
        H0mmEditFieldLabel        matlab.ui.control.Label
        fbEditField               matlab.ui.control.NumericEditField
        Label_18                  matlab.ui.control.Label
        nEditField                matlab.ui.control.NumericEditField
        nEditFieldLabel           matlab.ui.control.Label
        DEditField                matlab.ui.control.NumericEditField
        DmmEditFieldLabel         matlab.ui.control.Label
        dEditField                matlab.ui.control.NumericEditField
        mmLabel_4                 matlab.ui.control.Label
        kfnPanel                  matlab.ui.container.Panel
        fnEditField               matlab.ui.control.NumericEditField
        mmLabel_5                 matlab.ui.control.Label
        KvSingleEditField         matlab.ui.control.NumericEditField
        NmmEditFieldLabel         matlab.ui.control.Label
        supportNumEditField       matlab.ui.control.NumericEditField
        Label_17                  matlab.ui.control.Label
        KvTotalEditField          matlab.ui.control.NumericEditField
        Label_16                  matlab.ui.control.Label
        TEditField                matlab.ui.control.NumericEditField
        sEditFieldLabel_5         matlab.ui.control.Label
        vertConfixedEditField     matlab.ui.control.NumericEditField
        mmLabel_3                 matlab.ui.control.Label
        vertEditField_SD          matlab.ui.control.NumericEditField
        mmEditFieldLabel          matlab.ui.control.Label
        BCPanel                   matlab.ui.container.Panel
        nmaxEditField             matlab.ui.control.NumericEditField
        nmaxEditFieldLabel        matlab.ui.control.Label
        nminEditField             matlab.ui.control.NumericEditField
        nminEditFieldLabel        matlab.ui.control.Label
        fbmaxEditField            matlab.ui.control.NumericEditField
        fbmaxEditFieldLabel       matlab.ui.control.Label
        fbminEditField            matlab.ui.control.NumericEditField
        fbminEditFieldLabel       matlab.ui.control.Label
        DmaxEditField             matlab.ui.control.NumericEditField
        DmaxEditFieldLabel        matlab.ui.control.Label
        DminEditField             matlab.ui.control.NumericEditField
        DminEditFieldLabel        matlab.ui.control.Label
        dmaxEditField             matlab.ui.control.NumericEditField
        dmaxEditFieldLabel        matlab.ui.control.Label
        dminEditField             matlab.ui.control.NumericEditField
        dminEditFieldLabel        matlab.ui.control.Label
        Image2                    matlab.ui.control.Image
    end

    
    properties (Access = private)
        horiChosen
        waveDts % Description
        waveDts_RC % Description
    end
    
    methods (Access = private)
        
        function [] = configure_fn(app)
            app.fnEditField.Value = app.vertConfixedEditField.Value + ...
                app.TEditField.Value ^ 2 * 9806 / 4 / (pi ^ 2);
        end
        
        function [] = subeq_editor_confix(app)
        %SUBEQ_EDITOR_CONFIX 此处显示有关此函数的摘要
        %   此处显示详细说明
            sup = app.supportTT0EditField.Value;
            str = app.stressTT0EditField.Value;
            def = app.deformTT0EditField.Value;
            app.targetTT0EditField.Value = max(sup, str);
            app.targetTT0EditField.Value = max( ...
                        app.targetTT0EditField.Value, def);
            app.TT0EditField.Value = app.targetTT0EditField.Value;
            app.TEditField.Value = app.TT0EditField.Value * ...
                app.T0EditField.Value;
            app.KvTotalEditField.Value = 4 * pi ^ 2 * ... 
                app.m2tEditField.Value / (app.TEditField.Value ^ 2);
            app.KvSingleEditField.Value = app.KvTotalEditField.Value / ...
                app.supportNumEditField.Value;
            app.fnEditField.Value = app.vertConfixedEditField.Value + ...
                        app.TEditField.Value ^ 2 * 9806 / 4 / (pi ^ 2);
        end
        
        function [rT] = subeq_solver(~,target_rate, T_0, func)
        %SUBEQ_SOLVER 此处显示有关此函数的摘要
        %   此处显示详细说明
            F = @(x) func(T_0, x) - target_rate;
            rT0 = 1.0;
            options = optimoptions("fsolve");
            [rT, ~] = fsolve(F, rT0, options);
        end
        function [rT] = vbeta_solver(app)
            vbeta = app.vBetaEditField.Value;
            tg = app.tgField.Value;
            zeta = app.zetaField.Value;
            vzeta = app.vZetaEditField.Value;
            m1 = app.m1tEditField_2.Value;
            m2 = app.m2tEditField_2.Value;
            Th = app.ThEditField_2.Value;
            Ty = app.TyEditField_2.Value;
            Tv = app.TvEditField_2.Value;
            K1h = 4 * pi ^ 2 * m1 / (Th ^ 2);
            K2h = 4 * pi ^ 2 * m1 / (Ty ^ 2);
            Tv = app.TvEditField_2.Value;
            pga = app.mPGAEditField.Value;
            Ke = app.horiIsoKeEditField.Value;
            Xy = app.horiIsoXyEditField.Value;
%             F = @(x) rsa(tg, vzeta, Tv * x, pga) ...
%                 * vert_disp_confix(Ke, K1h, Xy) ...
%                 * vert_disp_confix(Ke, K2h, Xy) ...
%                 / a - vbeta;
            F = @(x) compute_vert_beta( ...
                4 * pi ^ 2 * m2 / ((Tv * x) ^ 2), ...
                vzeta, 4 * pi ^ 2 * m2 / (Tv ^ 2), zeta, m2, ...
                0.01 * m2, tg, pga) * vert_disp_confix(Ke, K1h, Xy) ...
                * vert_disp_confix(Ke, K2h, Xy) - vbeta;
            rT0 = 3;
            options = optimoptions("fsolve");
            [rT, ~] = fsolve(F, rT0, options);
            app.targetTT0EditField.Value = rT;
        end
        function [] = mesh_plot(~,handle, func)
        %MESH_PLOT 此处显示有关此函数的摘要
        %   此处显示详细说明
            x = 0.5:0.1:1.6;
            y = 1:0.1:4;
            [X, Y] = meshgrid(x, y);
            Z = func(X, Y);
            surf(handle, X, Y, Z)
            hold(handle, 'on');
        end
        
        function [] = rubberSupportUpdate(app)
            S2 = convertKeyName(app, app.rubberS2DropDown.Value);
            G = convertKeyName(app, app.rubberGDropDown.Value);
            D = convertKeyName(app, app.rubberDDropDown.Value);
            fileContent = fileread("rubber_support.json");
            data = jsondecode(fileContent);
            LNRdata = data.(S2).(G).LNR.(D);
            LRBdata = data.(S2).(G).LRB.(D);

            app.LNRKhEditField.Value = LNRdata.Kh * 1000;
            app.LRBKeEditField.Value = LRBdata.Ke * 1000;
            app.LRBKpEditField.Value = LRBdata.Kp * 1000;
            app.LRBQdEditField.Value = LRBdata.Qd * 1000;
            app.rubberLimDispEditField.Value = min(LNRdata.D * 0.55, ...
                LNRdata.t * 3);
            rubberTotalUpdate(app);
        end
        function [] = rubberTotalUpdate(app)
            LNRNum = app.LNRNumEditField.Value;
            LRBNum = app.LRBNumEditField.Value;
            LNRKh = app.LNRKhEditField.Value;
            LRBKe = app.LRBKeEditField.Value;
            LRBKp = app.LRBKpEditField.Value;
            LRBQd = app.LRBQdEditField.Value; 

            app.rubberKeEditField.Value = LNRKh * LNRNum + ...
                LRBKe * LRBNum;
            app.rubberKpEditField.Value = LNRKh * LNRNum + ...
                LRBKp * LRBNum;
            app.rubberXyEditField.Value = LRBQd / ...
                (LRBKe - LRBKp);
        end
        function betaUpdate(app)
            ke = app.horiIsoKeEditField.Value;
            kp = app.horiIsoKpEditField.Value;
            xy = app.horiIsoXyEditField.Value;
            m1 = app.m1tEditField_2.Value;
            m2 = app.m2tEditField_2.Value;
            mb = app.horimbEditField.Value;
            Th = app.ThEditField_2.Value;
            if ke == 0 || kp == 0 || mb == 0 || xy == 0
                return
            end
            %ks = 4 * pi ^ 2 * m1 * m2 / (m1 + m2) / (Th ^ 2);
            ks = 4 * pi ^ 2 * (m1 + m2) / (Th ^ 2);
            [kb, zeta_b, beta] = compute_hori_beta(ke, ...
                kp, ...
                xy, ks, ...
                app.zetaField.Value, app.horiSupportZetaEditField.Value,...
                m2+m1, mb, app.tgField.Value, ...
                app.mPGAEditField.Value);
            app.horiIsoKeqEditField.Value = kb;
            app.horiIsoZetaEditField.Value = zeta_b;
            app.horiBetaEditField.Value = beta;
        end
        function varName = convertKeyName(~,str)
            % 将字符串中的"."替换为"_"
            varName = strrep(str, '.', '_');
            % 将字符串的第一个字符转换为字母（如果它是一个数字）
            if isstrprop(varName(1), 'digit')
                varName = ['x', varName];
            end
        end
        
        function [] = earthquakeUpdate(app)
            fileContent = fileread("earthquake.json");
            data = jsondecode(fileContent);
            field = app.fieldDropDown.Value;
            group = app.groupDropDown.Value;
            degree = app.degreeDropDown.Value;
            app.eqTgEditField.Value = data.Tg.(field).(group);
            app.sPGAEditField.Value = data.PGA.(degree).small;
            app.mPGAEditField.Value = data.PGA.(degree).middle;
            app.gPGAEditField.Value = data.PGA.(degree).great;
            app.PGAEditField.Value = data.PGA.(degree).great;

            app.tgField.Value = data.Tg.(field).(group);
            if app.smgDropDown.Value == "g"
                app.accField.Value = data.PGA.(degree).great;
                app.tgField.Value = app.tgField.Value + 0.05;
            elseif app.smgDropDown.Value == "m"
                app.accField.Value = data.PGA.(degree).middle;
            else
                app.accField.Value = data.PGA.(degree).small;
            end
        end
        
        function [] = structureUpdate(app)
            app.m1tEditField.Value = app.m1tEditField_2.Value;
            app.m2tEditField.Value = app.m2tEditField_2.Value;
            app.TvEditField.Value = app.TvEditField_2.Value;
            app.ThEditField.Value = app.ThEditField_2.Value;
            app.TyEditField.Value = app.TyEditField_2.Value;
        end
        
        function [] = friSupportUpdate(app)
            F = str2double(app.friVertForceDropDown.Value) * 1000;
            T = str2double(app.friRockTDropDown.Value);
            mu = app.friMuEditField.Value;
            P = app.friPEditField.Value * 1000;
            Xy = app.friSigXyEditField.Value;
            if P == 0
                P = F;
            end

            if Xy == 0.0
                Xy = 2.5;
            end
            app.friREditField.Value = T ^ 2 / 4 / (pi ^ 2) * 9806;
            app.friSigKeEditField.Value = mu * P / Xy;
            app.friSigKpEditField.Value = P / app.friREditField.Value;
            friTotalUpdate(app);
        end
        function [] = friTotalUpdate(app)
            app.friTotalKeEditField.Value = app.friSigKeEditField.Value ...
                * app.friNumEditField.Value;
            app.friTotalKpEditField.Value = app.friSigKpEditField.Value ...
                * app.friNumEditField.Value;
            app.friTotalXyEditField.Value = app.friSigXyEditField.Value;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            stressHandle = app.liildUIFigure.findobj('Tag', 'stressaxes');
            mesh_plot(app, stressHandle, @stress_rate);
            supportHandle = app.liildUIFigure.findobj('Tag', 'supportaxes');
            mesh_plot(app, supportHandle, @support_rate);
            deformHandle = app.liildUIFigure.findobj('Tag', 'deformaxes');
            mesh_plot(app, deformHandle, @deform_rate);
            rubberSupportUpdate(app);
            earthquakeUpdate(app);
            app.waveDts = containers.Map();
            app.waveDts_RC = containers.Map();
        end

        % Button pushed function: openWavesButton
        function openButtonClicked(app, event)
            [filenames, pathnames] = uigetfile('*', ...
                    'Select Multiple Text Files');

            if isequal(filenames,0) || isequal(pathnames,0)
                disp('No files were selected.');
                return;
            end
            item = fullfile(pathnames, filenames);
            waveNames = app.chosenWavesList.Items;
            waveNames{length(waveNames) + 1} = item;
            app.chosenWavesList.Items = waveNames;

            app.waveDts(item) = app.dtField.Value;
        end

        % Double-clicked callback: chosenWavesList
        function chosenWavesListValueDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            value = app.chosenWavesList.Items{item};

            fileID = fopen(value, 'r');
            formatSpec = '%f';
            C = textscan(fileID, formatSpec, 'Delimiter', ...
                ',', 'EmptyValue', NaN, 'ReturnOnError', false);

            dataMatrix = cell2mat(C(:));
            wave = dataMatrix(~isnan(dataMatrix));
            fclose(fileID);

            zeta = app.zetaField.Value;
            ag = app.accField.Value;
            dt = app.waveDts(value);
            
            wave = ag / max(abs(wave)) * wave;
            [T, RSA] = response_spectra(wave,dt, zeta);
            axesHandle = app.liildUIFigure.findobj('Tag', 'rsaaxes');
            hold(axesHandle, 'on');
            plot(axesHandle, T, RSA, "LineStyle", "--","LineWidth",1);
            hold(axesHandle, 'off');
        end

        % Button pushed function: plotStdRSA
        function plotStdRSAButtonPushed(app, event)
            zeta = app.zetaField.Value;
            tg = app.tgField.Value;
            ag = app.accField.Value;
            [T, RSA] = gen_std_rsa(tg, zeta, ag);
            axesHandle = app.liildUIFigure.findobj('Tag', 'rsaaxes');
            
            plot(axesHandle, T, RSA, "Color",'k',"LineWidth",1);
            
        end

        % Button pushed function: clearWavesButton
        function clearWavesButtonPushed(app, event)
            app.chosenWavesList.Items = {};
            app.waveDts = containers.Map();
        end

        % Button pushed function: openWavesButton_RC
        function openWavesButton_RCPushed(app, event)
            [filenames, pathnames] = uigetfile('*', ...
                    'Select Multiple Text Files');

            if isequal(filenames,0) || isequal(pathnames,0)
                disp('No files were selected.');
                return;
            end
            item = fullfile(pathnames, filenames);
            waveNames = app.chosenWavesList_RC.Items;
            waveNames{length(waveNames) + 1} = item;
            app.chosenWavesList_RC.Items = waveNames;
            app.waveDts_RC(item) = app.dtField_RC.Value;
        end

        % Button pushed function: clearWavesButton_RC
        function clearWavesButton_RCPushed(app, event)
            app.chosenWavesList_RC.Items = {};
            app.waveDts_RC = containers.Map();
        end

        % Button pushed function: computeMaxButton
        function computeMaxButtonPushed(app, event)
            app.horiEditField.Value = 0;
            app.vertEditField.Value = 0;
            m1 = app.m1tEditField.Value;
            m2 = app.m2tEditField.Value;
            Th = app.ThEditField.Value;
            Ty = app.TyEditField.Value;
            Tv = app.TvEditField.Value;
            Ke = app.KeEditField.Value;
            Kp = app.KpEditField.Value;
            Xy = app.XyEditField.Value;
            C = app.CEditField.Value;
            alpha = app.alphaEditField.Value;
            TT0 = app.TT0EditField.Value;
            vzeta = app.vertZetaEditField.Value;
            step = app.stepEditField.Value;
            pga = app.PGAEditField.Value;
            K1h = 4 * pi ^ 2 * m1 / (Th ^ 2);
            K1y = 4 * pi ^ 2 * m1 / (Ty ^ 2);
            waveFiles = app.chosenWavesList_RC.Items;
            wb = waitbar(0, "计算中...");
            for i = 1:length(waveFiles)
                dt = app.waveDts_RC(waveFiles{i});
                waitbar(i / length(waveFiles), wb);

                command = sprintf("python nmk.py %s %f %f %f %f " + ...
                    "%f %f %f %f %f %f %f %f %f %f",waveFiles{i}, dt, ...
                    step, pga, m1, m2, Th, Tv, Ke, Kp, Xy, C, ...
                    alpha, TT0, vzeta);
                res = system(command);
                h = readmatrix("hori_temp");
                hm = max(abs(h));
                if Ke > 3 * K1h
                    hm = hm / (-17.585 * 0.34 ^ (Ke / K1h) + 1.651);
                end
                app.horiEditField.Value = max(app.horiEditField.Value, hm);
                
                command = sprintf("python nmk.py %s %f %f %f %f " + ...
                    "%f %f %f %f %f %f %f %f %f %f",waveFiles{i}, dt, ...
                    step, pga, m1, m2, Ty, Tv, Ke, Kp, Xy, C, ...
                    alpha, TT0, vzeta);
                res = system(command);
                h = readmatrix("hori_temp");
                hm = max(abs(h));
                if Ke > 3 * K1y
                    hm = hm / (-17.585 * 0.34 ^ (Ke / K1y) + 1.651);
                end
                app.horiEditField.Value = max(app.horiEditField.Value, hm);


                v = readmatrix("vert_temp");
                vm = max(abs(v));
                app.vertEditField.Value = max(app.vertEditField.Value, vm);
            end
            app.horiEditField.Value = app.horiEditField.Value;
            
            close(wb);
            msgbox("计算完成", "INFO");
            app.vertEditField_SD.Value = app.vertEditField.Value;
            
            
            rateh = vert_disp_confix(Ke, K1h, Xy);
            ratey = vert_disp_confix(Ke, K1y, Xy);
            app.vertConfixedEditField.Value = app.vertEditField_SD.Value ...
                * rateh * ratey;
            app.fnEditField.Value = app.vertConfixedEditField.Value + ...
                app.TEditField.Value ^ 2 * 9806 / 4 / (pi ^ 2);
        end

        % Button pushed function: passWavesButton
        function passWavesButtonPushed(app, event)
            app.chosenWavesList_RC.Items = app.chosenWavesList.Items;
            app.waveDts_RC = app.waveDts;
        end

        % Button pushed function: passWavesButton_RC
        function passWavesButton_RCPushed(app, event)
            app.chosenWavesList.Items = app.chosenWavesList_RC.Items;
            app.waveDts = app.waveDts_RC;
        end

        % Double-clicked callback: chosenWavesList_RC
        function chosenWavesList_RCDoubleClicked(app, event)
            item = event.InteractionInformation.Item;
            path = app.chosenWavesList_RC.Items{item};
            app.horiEditField.Value = 0;
            app.vertEditField.Value = 0;
            m1 = app.m1tEditField.Value;
            m2 = app.m2tEditField.Value;
            Th = app.ThEditField.Value;
            Tv = app.TvEditField.Value;
            Ke = app.KeEditField.Value;
            Kp = app.KpEditField.Value;
            Xy = app.XyEditField.Value;
            C = app.CEditField.Value;
            alpha = app.alphaEditField.Value;
            TT0 = app.TT0EditField.Value;
            vzeta = app.vertZetaEditField.Value;
            dt = app.waveDts_RC(path);
            step = app.stepEditField.Value;
            pga = app.PGAEditField.Value;
            command = sprintf("python nmk.py %s %f %f %f %f " + ...
                    "%f %f %f %f %f %f %f %f %f %f",path, dt, ...
                    step, pga, m1, m2, Th, Tv, Ke, Kp, Xy, C,...
                    alpha, TT0, vzeta);
            system(command);
            t = readmatrix("time_temp");
            h = readmatrix("hori_temp");
            v = readmatrix("vert_temp");
            hAxesHandle = app.liildUIFigure.findobj('Tag', 'horiaxes');
            vAxesHandle = app.liildUIFigure.findobj('Tag', 'vertaxes');
            plot(hAxesHandle, t, h, "Color",'k',"LineWidth",1);
            plot(vAxesHandle, t, v, "Color",'k',"LineWidth",1);
        end

        % Value changed function: deformTT0EditField, stressTT0EditField, 
        % ...and 1 other component
        function ssfTT0EditFieldValueChanged(app, event)
            subeq_editor_confix(app);
        end

        % Value changed function: targetTT0EditField
        function targetTT0EditFieldValueChanged(app, event)
            value = app.targetTT0EditField.Value;
            app.TT0EditField.Value = value;
            app.TEditField.Value = app.T0EditField.Value * value;
        end

        % Value changed function: T0EditField
        function T0EditFieldValueChanged(app, event)
            value = app.T0EditField.Value;
            app.TvEditField.Value = value;
            app.TEditField.Value = value * app.TT0EditField.Value;
            app.KvTotalEditField.Value = 4 * pi ^ 2 * ... 
                app.m2tEditField.Value / (app.TEditField.Value ^ 2);
            app.KvSingleEditField.Value = app.KvTotalEditField.Value / ...
                app.supportNumEditField.Value;
        end

        % Value changed function: dminEditField
        function dminEditFieldValueChanged(app, event)
            value = app.dminEditField.Value;
            app.DminEditField.Value = max(app.DminEditField.Value, value);
            app.DmaxEditField.Value = max(app.DmaxEditField.Value, ...
                app.DminEditField.Value);
            app.fbminEditField.Value = max(app.fbminEditField.Value, ...
                app.fnEditField.Value / app.nmaxEditField.Value + ...
                1.2 * value);
            app.fbmaxEditField.Value = max(app.fbminEditField.Value, ...
                app.fbmaxEditField.Value);
        end

        % Value changed function: vertEditField
        function vertEditFieldValueChanged(app, event)
            value = app.vertEditField.Value;
            app.vertEditField_SD.Value = value;
        end

        % Button pushed function: designButton
        function designButtonPushed(app, event)
            k = app.KvSingleEditField.Value;
            fn = app.fnEditField.Value;
            dmin = app.dminEditField.Value;
            dmax = app.dmaxEditField.Value;
            Dmin = app.DminEditField.Value;
            Dmax = app.DmaxEditField.Value;
            nmin = app.nminEditField.Value;
            nmax = app.nmaxEditField.Value;
            fbmin = app.fbminEditField.Value;
            fbmax = app.fbmaxEditField.Value;
            wb = waitbar(0, "计算中...");
            data = support_design(k, fn, ...
                                dmin, dmax, ...
                                Dmin, Dmax, ...
                                nmin, nmax, ...
                                fbmin, fbmax);
            waitbar(1, wb);
            close(wb);
            if data.success ~= 1
                msgbox("优化失败", "ERROR");
                return;
            end
            app.H0EditField.Value = data.fun;
            app.dEditField.Value = data.x(1);
            app.DEditField.Value = data.x(2);
            app.nEditField.Value = data.x(3);
            app.fbEditField.Value = data.x(4);
            msgbox("优化成功", "INFO");
        end

        % Value changed function: supportNumEditField
        function supportNumEditFieldValueChanged(app, event)
            value = app.supportNumEditField.Value;
            app.KvSingleEditField.Value = app.KvTotalEditField.Value ...
                / value;
        end

        % Value changed function: supportEditField
        function supportEditFieldValueChanged(app, event)
            sr = app.supportEditField.Value;
            T0 = app.T0EditField.Value;
            rT = subeq_solver(app, sr, T0, @support_rate);
            axesHandle = app.liildUIFigure.findobj('Tag', 'supportaxes');
            scatter3(axesHandle, T0, rT, sr, 'filled');
            text(axesHandle, T0 + 0.1, rT + 0.1, sr + 0.1, ...
                sprintf('(%.2f, %.2f, %.2f)', T0, rT, sr));
            scatterHandles = findobj(axesHandle, 'Type', 'Scatter');
            textHandles = findobj(axesHandle, 'Type', 'Text');
            if length(scatterHandles) > 1
                delete(scatterHandles(2:end));
                delete(textHandles(2:end));
            end
            app.supportTT0EditField.Value = rT;
            subeq_editor_confix(app);
        end

        % Value changed function: stressEditField
        function stressEditFieldValueChanged(app, event)
            sr = app.stressEditField.Value;
            T0 = app.T0EditField.Value;
            rT = subeq_solver(app, sr, T0, @stress_rate);
            axesHandle = app.liildUIFigure.findobj('Tag', 'stressaxes');
            scatter3(axesHandle, T0, rT, sr, 'filled');
            text(axesHandle, T0 + 0.1, rT + 0.1, sr + 0.1, ...
                sprintf('(%.2f, %.2f, %.2f)', T0, rT, sr));
            scatterHandles = findobj(axesHandle, 'Type', 'Scatter');
            textHandles = findobj(axesHandle, 'Type', 'Text');
            if length(scatterHandles) > 1
                delete(scatterHandles(2:end));
                delete(textHandles(2:end));
            end
            app.stressTT0EditField.Value = rT;
            subeq_editor_confix(app);
        end

        % Value changed function: deformEditField
        function deformEditFieldValueChanged(app, event)
            dr = app.deformEditField.Value;
            T0 = app.T0EditField.Value;
            rT = subeq_solver(app, dr, T0, @deform_rate);
            axesHandle = app.liildUIFigure.findobj('Tag', 'deformaxes');
            scatter3(axesHandle, T0, rT, dr, 'filled');
            text(axesHandle, T0 + 0.1, rT + 0.1, dr + 0.1, ...
                sprintf('(%.2f, %.2f, %.2f)', T0, rT, dr));
            scatterHandles = findobj(axesHandle, 'Type', 'Scatter');
            textHandles = findobj(axesHandle, 'Type', 'Text');
            if length(scatterHandles) > 1
                delete(scatterHandles(2:end));
                delete(textHandles(2:end));
            end
            app.deformTT0EditField.Value = rT;
            subeq_editor_confix(app);
        end

        % Button pushed function: deleteWavesButton
        function deleteWavesButtonPushed(app, event)
            to_delete = app.chosenWavesList.Value;
            total = app.chosenWavesList.Items;
            idx = ~ismember(total, to_delete);
            app.chosenWavesList.Items = total(idx);
            
            remove(app.waveDts, to_delete);
        end

        % Button pushed function: deleteWavesButton_RC
        function deleteWavesButton_RCPushed(app, event)
            to_delete = app.chosenWavesList_RC.Value;
            total = app.chosenWavesList_RC.Items;
            idx = ~ismember(total, to_delete);
            app.chosenWavesList_RC.Items = total(idx);
            
            remove(app.waveDts_RC, to_delete);
        end

        % Value changed function: vertEditField_SD
        function vertEditField_SDValueChanged(app, event)
            m1 = app.m1tEditField.Value;
            Th = app.ThEditField.Value;
            Ty = app.TyEditField.Value;
            Ke = app.KeEditField.Value;
            Xy = app.XyEditField.Value;
            K1h = 4 * pi ^ 2 * m1 / (Th ^ 2);
            k1y = 4 * pi ^ 2 * m1 / (Ty ^ 2);
            rateh = vert_disp_confix(Ke, K1h, Xy);
            ratey = vert_disp_confix(Ke, k1y, Xy);
            app.vertConfixedEditField.Value = app.vertEditField_SD.Value ...
                * rateh * ratey;
            configure_fn(app);
        end

        % Value changed function: vertConfixedEditField
        function vertConfixedEditFieldValueChanged(app, event)
            configure_fn(app);
        end

        % Value changed function: TEditField
        function TEditFieldValueChanged(app, event)
            configure_fn(app);
        end

        % Value changed function: rubberS2DropDown
        function rubberS2DropDownValueChanged(app, event)
            rubberSupportUpdate(app);
        end

        % Value changed function: rubberGDropDown
        function rubberGDropDownValueChanged(app, event)
            rubberSupportUpdate(app);
        end

        % Value changed function: rubberDDropDown
        function rubberDDropDownValueChanged(app, event)
            rubberSupportUpdate(app);
        end

        % Value changed function: fieldDropDown
        function fieldDropDownValueChanged(app, event)
            earthquakeUpdate(app);
        end

        % Value changed function: groupDropDown
        function groupDropDownValueChanged(app, event)
            earthquakeUpdate(app);
        end

        % Value changed function: degreeDropDown
        function degreeDropDownValueChanged(app, event)
            earthquakeUpdate(app);
        end

        % Value changed function: smgDropDown
        function smgDropDownValueChanged(app, event)
            earthquakeUpdate(app);
        end

        % Value changed function: m1tEditField_2
        function m1tEditField_2ValueChanged(app, event)
            structureUpdate(app);
        end

        % Value changed function: m2tEditField_2
        function m2tEditField_2ValueChanged(app, event)
            structureUpdate(app);
        end

        % Value changed function: ThEditField_2
        function ThEditField_2ValueChanged(app, event)
            structureUpdate(app);
        end

        % Value changed function: TvEditField_2
        function TvEditField_2ValueChanged(app, event)
            structureUpdate(app);
        end

        % Value changed function: horiIsoKeEditField
        function horiIsoKeEditFieldValueChanged(app, event)
            betaUpdate(app);
        end

        % Value changed function: horiIsoKpEditField
        function horiIsoKpEditFieldValueChanged(app, event)
            betaUpdate(app);
        end

        % Value changed function: horiIsoXyEditField
        function horiIsoXyEditFieldValueChanged(app, event)
            betaUpdate(app);
        end

        % Button pushed function: rubberChosenButton
        function rubberChosenButtonPushed(app, event)
            S2 = app.rubberS2DropDown.Value;
            G = app.rubberGDropDown.Value;
            D = app.rubberDDropDown.Value;
            LNRNum = app.LNRNumEditField.Value;
            LRBNum = app.LRBNumEditField.Value;

            Ke = app.rubberKeEditField.Value;
            Kp = app.rubberKpEditField.Value;
            Xy = app.rubberXyEditField.Value;
            Xm = app.rubberLimDispEditField.Value;

            newdata = [Ke Kp Xy, Xm];
            newitem = sprintf('rub-%s-%s-%s-%d-%d', S2, G, D, LNRNum, ...
                LRBNum);

            app.horiSupportChosenListBox.Items{end + 1} = newitem;
            app.horiChosen{end + 1} = newdata;
        end

        % Value changed function: friVertForceDropDown
        function friVertForceDropDownValueChanged(app, event)
            friSupportUpdate(app);
        end

        % Value changed function: friRockTDropDown
        function friRockTDropDownValueChanged(app, event)
            friSupportUpdate(app);
        end

        % Value changed function: friLimDispDropDown
        function friLimDispDropDownValueChanged(app, event)
            friSupportUpdate(app);
        end

        % Value changed function: friMuEditField
        function friMuEditFieldValueChanged(app, event)
            friSupportUpdate(app);
        end

        % Value changed function: friSigKeEditField
        function friSigKeEditFieldValueChanged(app, event)
            friTotalUpdate(app);
        end

        % Value changed function: friSigKpEditField
        function friSigKpEditFieldValueChanged(app, event)
            friTotalUpdate(app);
        end

        % Value changed function: friNumEditField
        function friNumEditFieldValueChanged(app, event)
            friTotalUpdate(app);
        end

        % Value changed function: friSigXyEditField
        function friSigXyEditFieldValueChanged(app, event)
            friSupportUpdate(app);
        end

        % Button pushed function: friChosenButton
        function friChosenButtonPushed(app, event)
            F = str2double(app.friVertForceDropDown.Value);
            P = app.friPEditField.Value;
            T = app.friRockTDropDown.Value;
            Xm = str2double(app.friLimDispDropDown.Value);
            mu = app.friMuEditField.Value;
            firNum = app.friNumEditField.Value;

            Ke = app.friTotalKeEditField.Value;
            Kp = app.friTotalKpEditField.Value;
            Xy = app.friTotalXyEditField.Value;

            if P == 0
                P = F;
            end

            newdata = [Ke Kp Xy, Xm];
            newitem = sprintf('fri-%d-%s-%d-%.2f-%d', P, T, Xm, mu, ...
                firNum);

            app.horiSupportChosenListBox.Items{end + 1} = newitem;
            app.horiChosen{end + 1} = newdata;
        end

        % Value changed function: friPEditField
        function friPEditFieldValueChanged(app, event)
            friSupportUpdate(app);
        end

        % Value changed function: LNRKhEditField
        function LNRKhEditFieldValueChanged(app, event)
            rubberTotalUpdate(app);
        end

        % Value changed function: LRBKeEditField
        function LRBKeEditFieldValueChanged(app, event)
            rubberTotalUpdate(app);
        end

        % Value changed function: LRBKpEditField
        function LRBKpEditFieldValueChanged(app, event)
            rubberTotalUpdate(app);
        end

        % Value changed function: LRBQdEditField
        function LRBQdEditFieldValueChanged(app, event)
            rubberTotalUpdate(app);
        end

        % Value changed function: LRBNumEditField
        function LRBNumEditFieldValueChanged2(app, event)
            rubberTotalUpdate(app);
        end

        % Value changed function: LNRNumEditField
        function LNRNumEditFieldValueChanged2(app, event)
            rubberTotalUpdate(app);
        end

        % Button pushed function: horiClearButton
        function horiClearButtonPushed(app, event)
            app.horiSupportChosenListBox.Items = {};
            app.horiChosen = {};
        end

        % Button pushed function: horiDeleteButton
        function horiDeleteButtonPushed(app, event)
            toDelete = app.horiSupportChosenListBox.Value;

            items = app.horiSupportChosenListBox.Items;
            idx = ~ismember(items, toDelete);
            app.horiSupportChosenListBox.Items = items(idx);
            app.horiChosen = app.horiChosen(idx);
        end

        % Button pushed function: horiComputeBetaButton
        function horiComputeBetaButtonPushed(app, event)
            n = length(app.horiChosen);
            if n == 0
                return
            end
            ke = zeros(1, n);
            kp = zeros(1, n);
            xy = zeros(1, n);
            for i=1:length(app.horiChosen)
                ke(i) = app.horiChosen{i}(1);
                kp(i) = app.horiChosen{i}(2);
                xy(i) = app.horiChosen{i}(3);
            end
            Xy = max(xy);
            app.horiIsoKeEditField.Value = sum(ke);
            force = 0;
            for i=1:length(app.horiChosen)
                force = force + ke(i) * xy(i) + ...
                    kp(i) * (Xy - xy(i));
            end
            app.horiIsoKpEditField.Value = sum(kp);
            app.horiIsoXyEditField.Value = (force - Xy * ...
                app.horiIsoKpEditField.Value) / ...
                (app.horiIsoKeEditField.Value - ...
                app.horiIsoKpEditField.Value);
            m1 = app.m1tEditField_2.Value;
            m2 = app.m2tEditField_2.Value;
            mb = app.horimbEditField.Value;
            if mb == 0
                return
            end
            Th = app.ThEditField_2.Value;
            
            %ks = 4 * pi ^ 2 * m1 * m2 / (m1 + m2) / (Th ^ 2);
            ks = 4 * pi ^ 2 * (m1 + m2) / (Th ^ 2);
            [kb, zeta_b, beta] = compute_hori_beta(ke, ...
                kp, ...
                xy, ks, ...
                app.zetaField.Value, app.horiSupportZetaEditField.Value,...
                m2+m1, mb, app.tgField.Value, ...
                app.mPGAEditField.Value);
            app.horiIsoKeqEditField.Value = kb;
            app.horiIsoZetaEditField.Value = zeta_b;
            app.horiBetaEditField.Value = beta;
        end

        % Button down function: responseCompute
        function responseComputeButtonDown(app, event)
            app.KeEditField.Value = app.horiIsoKeEditField.Value;
            app.KpEditField.Value = app.horiIsoKpEditField.Value;
            app.XyEditField.Value = app.horiIsoXyEditField.Value;
            limit = 0;
            if ~isempty(app.horiChosen)
                limit = app.horiChosen{1}(4);
                for i=1:length(app.horiChosen)
                    limit = min(limit, app.horiChosen{i}(4));
                end
            end
            app.horiLimitEditField.Value = limit;
        end

        % Button down function: confirmTv
        function confirmTvButtonDown(app, event)
            app.T0EditField.Value = app.TvEditField_2.Value;
        end

        % Value changed function: vBetaEditField
        function vBetaEditFieldValueChanged(app, event)
            vbeta_solver(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.liildUIFigure = uifigure('Visible', 'off');
            app.liildUIFigure.Name = 'liild';
            app.TabGroup = uitabgroup(app.liildUIFigure);
            app.inputWaves = uitab(app.TabGroup);
            app.inputWaves.Title = '预处理';
            app.RSAplot = uiaxes(app.inputWaves);
            title(app.RSAplot, '反应谱')
            xlabel(app.RSAplot, 'T(s)')
            ylabel(app.RSAplot, 'a(g)')
            zlabel(app.RSAplot, 'Z')
            app.RSAplot.Tag = 'rsaaxes';
            app.inputPanel = uipanel(app.inputWaves);
            app.inputPanel.Title = '导入地震波';
            app.GridLayout = uigridlayout(app.inputPanel);
            app.openWavesButton = uibutton(app.GridLayout, 'push');
            app.openWavesButton.ButtonPushedFcn = createCallbackFcn(app, @openButtonClicked, true);
            app.openWavesButton.Text = '打开';
            app.clearWavesButton = uibutton(app.GridLayout, 'push');
            app.clearWavesButton.ButtonPushedFcn = createCallbackFcn(app, @clearWavesButtonPushed, true);
            app.clearWavesButton.Text = '清除';
            app.passWavesButton = uibutton(app.GridLayout, 'push');
            app.passWavesButton.ButtonPushedFcn = createCallbackFcn(app, @passWavesButtonPushed, true);
            app.passWavesButton.Text = '传递';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = '地震波路径';
            app.chosenWavesList = uilistbox(app.GridLayout);
            app.chosenWavesList.Items = {};
            app.chosenWavesList.DoubleClickedFcn = createCallbackFcn(app, @chosenWavesListValueDoubleClicked, true);
            app.chosenWavesList.Value = {};
            app.sEditFieldLabel_2 = uilabel(app.GridLayout);
            app.sEditFieldLabel_2.Text = '时间步长(s)';
            app.dtField = uieditfield(app.GridLayout, 'numeric');
            app.dtField.Value = 0.02;
            app.deleteWavesButton = uibutton(app.GridLayout, 'push');
            app.deleteWavesButton.ButtonPushedFcn = createCallbackFcn(app, @deleteWavesButtonPushed, true);
            app.deleteWavesButton.Text = '删除';
            app.structPanel_2 = uipanel(app.inputWaves);
            app.structPanel_2.Title = '结构参数设置';
            app.m1tEditField_2Label = uilabel(app.structPanel_2);
            app.m1tEditField_2Label.Text = '框架质量：m1(t)';
            app.m1tEditField_2 = uieditfield(app.structPanel_2, 'numeric');
            app.m1tEditField_2.ValueDisplayFormat = '%.0f';
            app.m1tEditField_2.ValueChangedFcn = createCallbackFcn(app, @m1tEditField_2ValueChanged, true);
            app.m1tEditField_2.Value = 27178;
            app.m2tEditField_2Label = uilabel(app.structPanel_2);
            app.m2tEditField_2Label.Text = '屋盖质量：m2(t)';
            app.m2tEditField_2 = uieditfield(app.structPanel_2, 'numeric');
            app.m2tEditField_2.ValueDisplayFormat = '%.0f';
            app.m2tEditField_2.ValueChangedFcn = createCallbackFcn(app, @m2tEditField_2ValueChanged, true);
            app.m2tEditField_2.Value = 800;
            app.ThsLabel = uilabel(app.structPanel_2);
            app.ThsLabel.Text = '水平主周期：Tf(s)';
            app.ThEditField_2 = uieditfield(app.structPanel_2, 'numeric');
            app.ThEditField_2.ValueChangedFcn = createCallbackFcn(app, @ThEditField_2ValueChanged, true);
            app.ThEditField_2.Value = 0.84;
            app.TvsLabel_8 = uilabel(app.structPanel_2);
            app.TvsLabel_8.Text = '竖向周期：Tv(s)';
            app.TvEditField_2 = uieditfield(app.structPanel_2, 'numeric');
            app.TvEditField_2.ValueChangedFcn = createCallbackFcn(app, @TvEditField_2ValueChanged, true);
            app.TvEditField_2.Value = 0.33;
            app.TssLabel = uilabel(app.structPanel_2);
            app.TssLabel.Text = '水平次周期：Ts(s)';
            app.TyEditField_2 = uieditfield(app.structPanel_2, 'numeric');
            app.standardPanel = uipanel(app.inputWaves);
            app.standardPanel.Title = '规范谱参数';
            app.plotStdRSA = uibutton(app.standardPanel, 'push');
            app.plotStdRSA.ButtonPushedFcn = createCallbackFcn(app, @plotStdRSAButtonPushed, true);
            app.plotStdRSA.Text = '绘制';
            app.sEditFieldLabel = uilabel(app.standardPanel);
            app.sEditFieldLabel.Text = '特征周期(s)';
            app.tgField = uieditfield(app.standardPanel, 'numeric');
            app.tgField.Value = 0.45;
            app.Label_2 = uilabel(app.standardPanel);
            app.Label_2.Text = '阻尼比';
            app.zetaField = uieditfield(app.standardPanel, 'numeric');
            app.zetaField.Value = 0.03;
            app.Label_25 = uilabel(app.standardPanel);
            app.Label_25.Text = '地震';
            app.smgDropDown = uidropdown(app.standardPanel);
            app.smgDropDown.Items = {'常遇地震', '设防地震', '罕遇地震'};
            app.smgDropDown.ItemsData = {'s', 'm', 'g'};
            app.smgDropDown.ValueChangedFcn = createCallbackFcn(app, @smgDropDownValueChanged, true);
            app.smgDropDown.Value = 's';
            app.PGAgLabel = uilabel(app.standardPanel);
            app.PGAgLabel.Text = 'PGA(g)';
            app.accField = uieditfield(app.standardPanel, 'numeric');
            app.accField.Value = 0.4;
            app.earthquakePanel = uipanel(app.inputWaves);
            app.earthquakePanel.Title = '地震参数';
            app.sLabel_2 = uilabel(app.earthquakePanel);
            app.sLabel_2.Text = '特征周期(s)';
            app.eqTgEditField = uieditfield(app.earthquakePanel, 'numeric');
            app.sLabel_3 = uilabel(app.earthquakePanel);
            app.sLabel_3.Text = '设防地震PGA(g)';
            app.mPGAEditField = uieditfield(app.earthquakePanel, 'numeric');
            app.sLabel_4 = uilabel(app.earthquakePanel);
            app.sLabel_4.Text = '罕遇地震PGA(g)';
            app.gPGAEditField = uieditfield(app.earthquakePanel, 'numeric');
            app.Label_22 = uilabel(app.earthquakePanel);
            app.Label_22.Text = '场地类别';
            app.fieldDropDown = uidropdown(app.earthquakePanel);
            app.fieldDropDown.Items = {'I0', 'I1', 'II', 'III', 'IV'};
            app.fieldDropDown.ValueChangedFcn = createCallbackFcn(app, @fieldDropDownValueChanged, true);
            app.fieldDropDown.Value = 'I0';
            app.Label_23 = uilabel(app.earthquakePanel);
            app.Label_23.Text = '分组';
            app.groupDropDown = uidropdown(app.earthquakePanel);
            app.groupDropDown.Items = {'第一组', '第二组', '第三组'};
            app.groupDropDown.ItemsData = {'one', 'two', 'three'};
            app.groupDropDown.ValueChangedFcn = createCallbackFcn(app, @groupDropDownValueChanged, true);
            app.groupDropDown.Value = 'one';
            app.Label_24 = uilabel(app.earthquakePanel);
            app.Label_24.Text = '设防烈度';
            app.degreeDropDown = uidropdown(app.earthquakePanel);
            app.degreeDropDown.Items = {'6度(0.05g)', '7度(0.10g)', '7度(0.15g)', '8度(0.20g)', '8度(0.30g)', '9度(0.40g)'};
            app.degreeDropDown.ItemsData = {'degree60', 'degree70', 'degree75', 'degree80', 'degree85', 'degree90'};
            app.degreeDropDown.ValueChangedFcn = createCallbackFcn(app, @degreeDropDownValueChanged, true);
            app.degreeDropDown.Value = 'degree60';
            app.sLabel_5 = uilabel(app.earthquakePanel);
            app.sLabel_5.Text = '常遇地震PGA(g)';
            app.sPGAEditField = uieditfield(app.earthquakePanel, 'numeric');
            app.horiIsoDesign = uitab(app.TabGroup);
            app.horiIsoDesign.Title = '水平隔震设计';
            app.horiIsoLayerPanel = uipanel(app.horiIsoDesign);
            app.horiIsoLayerPanel.Title = '水平隔震层';
            app.KeNmmLabel_3 = uilabel(app.horiIsoLayerPanel);
            app.KeNmmLabel_3.Text = '初始刚度：Ke(N/mm)';
            app.horiIsoKeEditField = uieditfield(app.horiIsoLayerPanel, 'numeric');
            app.horiIsoKeEditField.ValueDisplayFormat = '%.0f';
            app.horiIsoKeEditField.ValueChangedFcn = createCallbackFcn(app, @horiIsoKeEditFieldValueChanged, true);
            app.KpNmmLabel_2 = uilabel(app.horiIsoLayerPanel);
            app.KpNmmLabel_2.Text = '屈服后刚度：Kp(N/mm)';
            app.horiIsoKpEditField = uieditfield(app.horiIsoLayerPanel, 'numeric');
            app.horiIsoKpEditField.ValueDisplayFormat = '%.0f';
            app.horiIsoKpEditField.ValueChangedFcn = createCallbackFcn(app, @horiIsoKpEditFieldValueChanged, true);
            app.XymmLabel = uilabel(app.horiIsoLayerPanel);
            app.XymmLabel.Text = '屈服位移：Xy(mm)';
            app.horiIsoXyEditField = uieditfield(app.horiIsoLayerPanel, 'numeric');
            app.horiIsoXyEditField.ValueChangedFcn = createCallbackFcn(app, @horiIsoXyEditFieldValueChanged, true);
            app.alphaLabel = uilabel(app.horiIsoLayerPanel);
            app.alphaLabel.Text = '减震系数：beta';
            app.horiBetaEditField = uieditfield(app.horiIsoLayerPanel, 'numeric');
            app.KeqNmmLabel = uilabel(app.horiIsoLayerPanel);
            app.KeqNmmLabel.Text = '等效刚度：Keq(N/mm)';
            app.horiIsoKeqEditField = uieditfield(app.horiIsoLayerPanel, 'numeric');
            app.horiIsoKeqEditField.ValueDisplayFormat = '%.0f';
            app.zetaLabel = uilabel(app.horiIsoLayerPanel);
            app.zetaLabel.Text = '等效阻尼比：zeta';
            app.horiIsoZetaEditField = uieditfield(app.horiIsoLayerPanel, 'numeric');
            app.supportTabGroup = uitabgroup(app.horiIsoDesign);
            app.rubberTab = uitab(app.supportTabGroup);
            app.rubberTab.Title = '橡胶支座';
            app.S2Label = uilabel(app.rubberTab);
            app.S2Label.Text = '橡胶第二形状系数：S2';
            app.rubberS2DropDown = uidropdown(app.rubberTab);
            app.rubberS2DropDown.Items = {'5', '5.45'};
            app.rubberS2DropDown.ValueChangedFcn = createCallbackFcn(app, @rubberS2DropDownValueChanged, true);
            app.rubberS2DropDown.Value = '5';
            app.GLabel = uilabel(app.rubberTab);
            app.GLabel.Text = '橡胶的剪切模量：G(MPa)';
            app.rubberGDropDown = uidropdown(app.rubberTab);
            app.rubberGDropDown.Items = {'0.392', '0.49'};
            app.rubberGDropDown.ValueChangedFcn = createCallbackFcn(app, @rubberGDropDownValueChanged, true);
            app.rubberGDropDown.Value = '0.392';
            app.DmmLabel = uilabel(app.rubberTab);
            app.DmmLabel.Text = '橡胶支座直径：D(mm)';
            app.rubberDDropDown = uidropdown(app.rubberTab);
            app.rubberDDropDown.Items = {'300', '400', '500', '600', '700', '800', '900', '1000', '1100', '1200', '1300', '1400', '1500'};
            app.rubberDDropDown.ValueChangedFcn = createCallbackFcn(app, @rubberDDropDownValueChanged, true);
            app.rubberDDropDown.Value = '300';
            app.Label_19 = uilabel(app.rubberTab);
            app.Label_19.Text = '天然橡胶支座数量';
            app.LNRNumEditField = uieditfield(app.rubberTab, 'numeric');
            app.LNRNumEditField.ValueChangedFcn = createCallbackFcn(app, @LNRNumEditFieldValueChanged2, true);
            app.Label_20 = uilabel(app.rubberTab);
            app.Label_20.Text = '铅芯橡胶支座数量';
            app.LRBNumEditField = uieditfield(app.rubberTab, 'numeric');
            app.LRBNumEditField.ValueChangedFcn = createCallbackFcn(app, @LRBNumEditFieldValueChanged2, true);
            app.KhNmmLabel = uilabel(app.rubberTab);
            app.KhNmmLabel.Text = 'LNR水平刚度：Kh(N/mm)';
            app.LNRKhEditField = uieditfield(app.rubberTab, 'numeric');
            app.LNRKhEditField.ValueDisplayFormat = '%.0f';
            app.LNRKhEditField.ValueChangedFcn = createCallbackFcn(app, @LNRKhEditFieldValueChanged, true);
            app.KeNmmLabel_2 = uilabel(app.rubberTab);
            app.KeNmmLabel_2.Text = 'LRB初始刚度：Ke(N/mm)';
            app.LRBKeEditField = uieditfield(app.rubberTab, 'numeric');
            app.LRBKeEditField.ValueDisplayFormat = '%.0f';
            app.LRBKeEditField.ValueChangedFcn = createCallbackFcn(app, @LRBKeEditFieldValueChanged, true);
            app.KpNmmLabel = uilabel(app.rubberTab);
            app.KpNmmLabel.Text = 'LRB屈服后刚度：Kp(N/mm)';
            app.LRBKpEditField = uieditfield(app.rubberTab, 'numeric');
            app.LRBKpEditField.ValueDisplayFormat = '%.0f';
            app.LRBKpEditField.ValueChangedFcn = createCallbackFcn(app, @LRBKpEditFieldValueChanged, true);
            app.QdNmmLabel = uilabel(app.rubberTab);
            app.QdNmmLabel.Text = 'LRB屈服力：Qd(N/mm)';
            app.LRBQdEditField = uieditfield(app.rubberTab, 'numeric');
            app.LRBQdEditField.ValueDisplayFormat = '%.0f';
            app.LRBQdEditField.ValueChangedFcn = createCallbackFcn(app, @LRBQdEditFieldValueChanged, true);
            app.tmmLabel_2 = uilabel(app.rubberTab);
            app.tmmLabel_2.Text = '限制位移：Xm(mm)';
            app.rubberLimDispEditField = uieditfield(app.rubberTab, 'numeric');
            app.rubberLimDispEditField.ValueDisplayFormat = '%.0f';
            app.rubberChosenButton = uibutton(app.rubberTab, 'push');
            app.rubberChosenButton.ButtonPushedFcn = createCallbackFcn(app, @rubberChosenButtonPushed, true);
            app.rubberChosenButton.Text = '确定';
            app.rubberIntPanel = uipanel(app.rubberTab);
            app.rubberIntPanel.Title = '所选性能';
            app.XymmLabel_3 = uilabel(app.rubberIntPanel);
            app.XymmLabel_3.Text = '屈服位移：Xy(mm)';
            app.rubberXyEditField = uieditfield(app.rubberIntPanel, 'numeric');
            app.KeNmmEditFieldLabel_2 = uilabel(app.rubberIntPanel);
            app.KeNmmEditFieldLabel_2.Text = '初始刚度：Ke(N/mm)';
            app.rubberKeEditField = uieditfield(app.rubberIntPanel, 'numeric');
            app.rubberKeEditField.ValueDisplayFormat = '%.0f';
            app.KpNmmLabel_4 = uilabel(app.rubberIntPanel);
            app.KpNmmLabel_4.Text = '屈服后刚度：Kp(N/mm)';
            app.rubberKpEditField = uieditfield(app.rubberIntPanel, 'numeric');
            app.rubberKpEditField.ValueDisplayFormat = '%.0f';
            app.frictionTab = uitab(app.supportTabGroup);
            app.frictionTab.Title = '摩擦摆支座';
            app.KeNmmEditFieldLabel = uilabel(app.frictionTab);
            app.KeNmmEditFieldLabel.Text = '初始刚度：Ke(N/mm)';
            app.friSigKeEditField = uieditfield(app.frictionTab, 'numeric');
            app.friSigKeEditField.ValueDisplayFormat = '%.0f';
            app.friSigKeEditField.ValueChangedFcn = createCallbackFcn(app, @friSigKeEditFieldValueChanged, true);
            app.KpNmmLabel_3 = uilabel(app.frictionTab);
            app.KpNmmLabel_3.Text = '屈服后刚度：Kp(N/mm)';
            app.friSigKpEditField = uieditfield(app.frictionTab, 'numeric');
            app.friSigKpEditField.ValueDisplayFormat = '%.0f';
            app.friSigKpEditField.ValueChangedFcn = createCallbackFcn(app, @friSigKpEditFieldValueChanged, true);
            app.XymmLabel_2 = uilabel(app.frictionTab);
            app.XymmLabel_2.Text = '屈服位移：Xy(mm)';
            app.friSigXyEditField = uieditfield(app.frictionTab, 'numeric');
            app.friSigXyEditField.ValueChangedFcn = createCallbackFcn(app, @friSigXyEditFieldValueChanged, true);
            app.friSigXyEditField.Value = 2.5;
            app.kNLabel = uilabel(app.frictionTab);
            app.kNLabel.Text = '支座竖向力(kN)';
            app.friVertForceDropDown = uidropdown(app.frictionTab);
            app.friVertForceDropDown.Items = {'1000', '1500', '2000', '2500', '3000', '3500', '4000', '4500', '5000', '6000', '7000', '8000', '9000', '10000', '12500', '15000', '17500', '20000', '25000', '30000'};
            app.friVertForceDropDown.ValueChangedFcn = createCallbackFcn(app, @friVertForceDropDownValueChanged, true);
            app.friVertForceDropDown.Value = '1000';
            app.Label_28 = uilabel(app.frictionTab);
            app.Label_28.Text = '摆动周期(s)';
            app.friRockTDropDown = uidropdown(app.frictionTab);
            app.friRockTDropDown.Items = {'2', '3', '3.5', '4', '4.5', '5', '5.5', '6'};
            app.friRockTDropDown.ValueChangedFcn = createCallbackFcn(app, @friRockTDropDownValueChanged, true);
            app.friRockTDropDown.Value = '2';
            app.Label_29 = uilabel(app.frictionTab);
            app.Label_29.Text = '极限位移';
            app.friLimDispDropDown = uidropdown(app.frictionTab);
            app.friLimDispDropDown.Items = {'200', '250', '300', '350', '400', '450', '500', '550', '600'};
            app.friLimDispDropDown.ValueChangedFcn = createCallbackFcn(app, @friLimDispDropDownValueChanged, true);
            app.friLimDispDropDown.Value = '200';
            app.Label_30 = uilabel(app.frictionTab);
            app.Label_30.Text = '摩擦因数';
            app.friMuEditField = uieditfield(app.frictionTab, 'numeric');
            app.friMuEditField.ValueChangedFcn = createCallbackFcn(app, @friMuEditFieldValueChanged, true);
            app.friMuEditField.Value = 0.05;
            app.Label_31 = uilabel(app.frictionTab);
            app.Label_31.Text = '支座数量：';
            app.friNumEditField = uieditfield(app.frictionTab, 'numeric');
            app.friNumEditField.ValueDisplayFormat = '%.0f';
            app.friNumEditField.ValueChangedFcn = createCallbackFcn(app, @friNumEditFieldValueChanged, true);
            app.RmmLabel = uilabel(app.frictionTab);
            app.RmmLabel.Text = '等效半径：R(mm)';
            app.friREditField = uieditfield(app.frictionTab, 'numeric');
            app.friREditField.ValueDisplayFormat = '%.0f';
            app.PkNLabel = uilabel(app.frictionTab);
            app.PkNLabel.Text = '实际竖向力：P(kN)';
            app.friPEditField = uieditfield(app.frictionTab, 'numeric');
            app.friPEditField.ValueDisplayFormat = '%.0f';
            app.friPEditField.ValueChangedFcn = createCallbackFcn(app, @friPEditFieldValueChanged, true);
            app.friIntPanel = uipanel(app.frictionTab);
            app.friIntPanel.Title = '所选性能';
            app.XymmLabel_4 = uilabel(app.friIntPanel);
            app.XymmLabel_4.Text = '屈服位移：Xy(mm)';
            app.friTotalXyEditField = uieditfield(app.friIntPanel, 'numeric');
            app.friTotalXyEditField.Value = 2.5;
            app.KpNmmLabel_5 = uilabel(app.friIntPanel);
            app.KpNmmLabel_5.Text = '屈服后刚度：Kp(N/mm)';
            app.friTotalKpEditField = uieditfield(app.friIntPanel, 'numeric');
            app.friTotalKpEditField.ValueDisplayFormat = '%.0f';
            app.KeNmmEditFieldLabel_3 = uilabel(app.friIntPanel);
            app.KeNmmEditFieldLabel_3.Text = '初始刚度：Ke(N/mm)';
            app.friTotalKeEditField = uieditfield(app.friIntPanel, 'numeric');
            app.friTotalKeEditField.ValueDisplayFormat = '%.0f';
            app.friChosenButton = uibutton(app.frictionTab, 'push');
            app.friChosenButton.ButtonPushedFcn = createCallbackFcn(app, @friChosenButtonPushed, true);
            app.friChosenButton.Text = '确定';
            app.horiSupportChosenListBox = uilistbox(app.horiIsoDesign);
            app.horiSupportChosenListBox.Items = {};
            app.horiSupportChosenListBox.Value = {};
            app.CNmmsLabel = uilabel(app.horiIsoDesign);
            app.CNmmsLabel.Text = '隔震层质量：mb(t)';
            app.horimbEditField = uieditfield(app.horiIsoDesign, 'numeric');
            app.horimbEditField.Value = 100;
            app.Label_27 = uilabel(app.horiIsoDesign);
            app.Label_27.Text = '附加阻尼比：';
            app.horiSupportZetaEditField = uieditfield(app.horiIsoDesign, 'numeric');
            app.horiSupportZetaEditField.Limits = [0 1];
            app.horiComputeBetaButton = uibutton(app.horiIsoDesign, 'push');
            app.horiComputeBetaButton.ButtonPushedFcn = createCallbackFcn(app, @horiComputeBetaButtonPushed, true);
            app.horiComputeBetaButton.Text = '计算';
            app.horiDeleteButton = uibutton(app.horiIsoDesign, 'push');
            app.horiDeleteButton.ButtonPushedFcn = createCallbackFcn(app, @horiDeleteButtonPushed, true);
            app.horiDeleteButton.Text = '删除';
            app.horiClearButton = uibutton(app.horiIsoDesign, 'push');
            app.horiClearButton.ButtonPushedFcn = createCallbackFcn(app, @horiClearButtonPushed, true);
            app.horiClearButton.Text = '清除';
            app.confirmTv = uitab(app.TabGroup);
            app.confirmTv.Title = '竖向隔震周期确定';
            app.confirmTv.ButtonDownFcn = createCallbackFcn(app, @confirmTvButtonDown, true);
            app.stressRateAxes = uiaxes(app.confirmTv);
            title(app.stressRateAxes, '杆件平均应力减震率')
            xlabel(app.stressRateAxes, 'T_0(s)')
            ylabel(app.stressRateAxes, 'T/T_0')
            zlabel(app.stressRateAxes, '减震率(%)')
            app.stressRateAxes.Tag = 'stressaxes';
            app.supportRateAxes = uiaxes(app.confirmTv);
            title(app.supportRateAxes, '支座反力平均减震率')
            xlabel(app.supportRateAxes, 'T_0(s)')
            ylabel(app.supportRateAxes, 'T/T_0')
            zlabel(app.supportRateAxes, '减震率(%)')
            app.supportRateAxes.Tag = 'supportaxes';
            app.deformRateAxes = uiaxes(app.confirmTv);
            title(app.deformRateAxes, '壳顶变形减震率')
            xlabel(app.deformRateAxes, 'T_0(s)')
            ylabel(app.deformRateAxes, 'T/T_0')
            zlabel(app.deformRateAxes, '减震率(%)')
            app.deformRateAxes.Tag = 'deformaxes';
            app.Label_14 = uilabel(app.confirmTv);
            app.Label_14.Text = '目标隔震倍率：';
            app.targetTT0EditField = uieditfield(app.confirmTv, 'numeric');
            app.targetTT0EditField.ValueChangedFcn = createCallbackFcn(app, @targetTT0EditFieldValueChanged, true);
            app.targetTT0EditField.Value = 1;
            app.supportPanel = uipanel(app.confirmTv);
            app.supportPanel.Title = '支座反力';
            app.Label_10 = uilabel(app.supportPanel);
            app.Label_10.Text = '支座反力减震率';
            app.supportEditField = uieditfield(app.supportPanel, 'numeric');
            app.supportEditField.Limits = [-100 100];
            app.supportEditField.ValueChangedFcn = createCallbackFcn(app, @supportEditFieldValueChanged, true);
            app.supportEditField.Value = 50;
            app.Label_11 = uilabel(app.supportPanel);
            app.Label_11.Text = '最小减震倍率';
            app.supportTT0EditField = uieditfield(app.supportPanel, 'numeric');
            app.supportTT0EditField.ValueChangedFcn = createCallbackFcn(app, @ssfTT0EditFieldValueChanged, true);
            app.supportTT0EditField.Value = 1;
            app.stressPanel = uipanel(app.confirmTv);
            app.stressPanel.Title = '杆件应力';
            app.Label_8 = uilabel(app.stressPanel);
            app.Label_8.Text = '杆件应力减震率';
            app.stressEditField = uieditfield(app.stressPanel, 'numeric');
            app.stressEditField.Limits = [-100 100];
            app.stressEditField.ValueChangedFcn = createCallbackFcn(app, @stressEditFieldValueChanged, true);
            app.stressEditField.Value = 50;
            app.Label_9 = uilabel(app.stressPanel);
            app.Label_9.Text = '最小减震倍率';
            app.stressTT0EditField = uieditfield(app.stressPanel, 'numeric');
            app.stressTT0EditField.ValueChangedFcn = createCallbackFcn(app, @ssfTT0EditFieldValueChanged, true);
            app.stressTT0EditField.Value = 1;
            app.deformPanel = uipanel(app.confirmTv);
            app.deformPanel.Title = '壳顶变形';
            app.Label_12 = uilabel(app.deformPanel);
            app.Label_12.Text = '壳顶变形减震率';
            app.deformEditField = uieditfield(app.deformPanel, 'numeric');
            app.deformEditField.Limits = [-100 100];
            app.deformEditField.ValueChangedFcn = createCallbackFcn(app, @deformEditFieldValueChanged, true);
            app.deformEditField.Value = 50;
            app.Label_13 = uilabel(app.deformPanel);
            app.Label_13.Text = '最小减震倍率';
            app.deformTT0EditField = uieditfield(app.deformPanel, 'numeric');
            app.deformTT0EditField.ValueChangedFcn = createCallbackFcn(app, @ssfTT0EditFieldValueChanged, true);
            app.deformTT0EditField.Value = 1;
            app.Label_15 = uilabel(app.confirmTv);
            app.Label_15.Text = '结构竖向周期(s)：';
            app.T0EditField = uieditfield(app.confirmTv, 'numeric');
            app.T0EditField.ValueChangedFcn = createCallbackFcn(app, @T0EditFieldValueChanged, true);
            app.T0EditField.Value = 0.33;
            app.Label_32 = uilabel(app.confirmTv);
            app.Label_32.Text = '支座动反力比：';
            app.vBetaEditField = uieditfield(app.confirmTv, 'numeric');
            app.vBetaEditField.ValueChangedFcn = createCallbackFcn(app, @vBetaEditFieldValueChanged, true);
            app.Label_33 = uilabel(app.confirmTv);
            app.Label_33.Text = '阻尼比：';
            app.vZetaEditField = uieditfield(app.confirmTv, 'numeric');
            app.vZetaEditField.Value = 0.2;
            app.responseCompute = uitab(app.TabGroup);
            app.responseCompute.Title = '响应计算';
            app.responseCompute.ButtonDownFcn = createCallbackFcn(app, @responseComputeButtonDown, true);
            app.vertAxes = uiaxes(app.responseCompute);
            title(app.vertAxes, '竖向隔震层响应')
            xlabel(app.vertAxes, 't(s)')
            ylabel(app.vertAxes, 'u(mm)')
            zlabel(app.vertAxes, 'Z')
            app.vertAxes.Tag = 'vertaxes';
            app.horiAxes = uiaxes(app.responseCompute);
            title(app.horiAxes, '水平隔震层响应')
            xlabel(app.horiAxes, 't(s)')
            ylabel(app.horiAxes, 'u(mm)')
            zlabel(app.horiAxes, 'Z')
            app.horiAxes.Tag = 'horiaxes';
            app.Image = uiimage(app.responseCompute);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'model.svg');
            app.inputPanel_RC = uipanel(app.responseCompute);
            app.inputPanel_RC.Title = '导入地震波';
            app.GridLayout2 = uigridlayout(app.inputPanel_RC);
            app.openWavesButton_RC = uibutton(app.GridLayout2, 'push');
            app.openWavesButton_RC.ButtonPushedFcn = createCallbackFcn(app, @openWavesButton_RCPushed, true);
            app.openWavesButton_RC.Text = '打开';
            app.clearWavesButton_RC = uibutton(app.GridLayout2, 'push');
            app.clearWavesButton_RC.ButtonPushedFcn = createCallbackFcn(app, @clearWavesButton_RCPushed, true);
            app.clearWavesButton_RC.Text = '清除';
            app.passWavesButton_RC = uibutton(app.GridLayout2, 'push');
            app.passWavesButton_RC.ButtonPushedFcn = createCallbackFcn(app, @passWavesButton_RCPushed, true);
            app.passWavesButton_RC.Text = '传递';
            app.Label_3 = uilabel(app.GridLayout2);
            app.Label_3.Text = '地震波路径';
            app.chosenWavesList_RC = uilistbox(app.GridLayout2);
            app.chosenWavesList_RC.Items = {};
            app.chosenWavesList_RC.DoubleClickedFcn = createCallbackFcn(app, @chosenWavesList_RCDoubleClicked, true);
            app.chosenWavesList_RC.Value = {};
            app.sLabel = uilabel(app.GridLayout2);
            app.sLabel.Text = '时间步长(s)：';
            app.dtField_RC = uieditfield(app.GridLayout2, 'numeric');
            app.dtField_RC.Value = 0.02;
            app.deleteWavesButton_RC = uibutton(app.GridLayout2, 'push');
            app.deleteWavesButton_RC.ButtonPushedFcn = createCallbackFcn(app, @deleteWavesButton_RCPushed, true);
            app.deleteWavesButton_RC.Text = '删除';
            app.structPanel = uipanel(app.responseCompute);
            app.structPanel.Title = '结构参数设置';
            app.m1tEditFieldLabel = uilabel(app.structPanel);
            app.m1tEditFieldLabel.Text = '框架质量：m1(t)';
            app.m1tEditField = uieditfield(app.structPanel, 'numeric');
            app.m1tEditField.ValueDisplayFormat = '%.0f';
            app.m1tEditField.Value = 27178;
            app.m2tEditFieldLabel = uilabel(app.structPanel);
            app.m2tEditFieldLabel.Text = '屋盖质量：m2(t)';
            app.m2tEditField = uieditfield(app.structPanel, 'numeric');
            app.m2tEditField.ValueDisplayFormat = '%.0f';
            app.m2tEditField.Value = 800;
            app.TfsLabel = uilabel(app.structPanel);
            app.TfsLabel.Text = '水平主周期：Tf(s)';
            app.ThEditField = uieditfield(app.structPanel, 'numeric');
            app.ThEditField.Value = 0.84;
            app.TvsLabel = uilabel(app.structPanel);
            app.TvsLabel.Text = '竖向周期：Tv(s)';
            app.TvEditField = uieditfield(app.structPanel, 'numeric');
            app.TvEditField.Value = 0.33;
            app.TssLabel_2 = uilabel(app.structPanel);
            app.TssLabel_2.Text = '水平次周期：Ts(s)';
            app.TyEditField = uieditfield(app.structPanel, 'numeric');
            app.horiPanel = uipanel(app.responseCompute);
            app.horiPanel.Title = '水平隔震层设置';
            app.TvsLabel_2 = uilabel(app.horiPanel);
            app.TvsLabel_2.Text = '初始刚度：Ke(N/mm)';
            app.KeEditField = uieditfield(app.horiPanel, 'numeric');
            app.KeEditField.ValueDisplayFormat = '%.0f';
            app.KeEditField.Value = 402880;
            app.TvsLabel_4 = uilabel(app.horiPanel);
            app.TvsLabel_4.Text = '屈服位移：Xy(mm)';
            app.XyEditField = uieditfield(app.horiPanel, 'numeric');
            app.XyEditField.ValueDisplayFormat = '%.3f';
            app.XyEditField.Value = 10.7112;
            app.TvsLabel_5 = uilabel(app.horiPanel);
            app.TvsLabel_5.Text = '阻尼系数：C(N·mm/s)';
            app.CEditField = uieditfield(app.horiPanel, 'numeric');
            app.CEditField.ValueDisplayFormat = '%.0f';
            app.TvsLabel_7 = uilabel(app.horiPanel);
            app.TvsLabel_7.Text = '阻尼指数：alpha';
            app.alphaEditField = uieditfield(app.horiPanel, 'numeric');
            app.alphaEditField.Value = 1;
            app.TvsLabel_3 = uilabel(app.horiPanel);
            app.TvsLabel_3.Text = '屈服后刚度：Kp(N/mm)';
            app.KpEditField = uieditfield(app.horiPanel, 'numeric');
            app.KpEditField.ValueDisplayFormat = '%.0f';
            app.KpEditField.Value = 58240;
            app.vertPanel = uipanel(app.responseCompute);
            app.vertPanel.Title = '竖向隔震层设置';
            app.TvsLabel_6 = uilabel(app.vertPanel);
            app.TvsLabel_6.Text = '隔震倍率：T/T0';
            app.TT0EditField = uieditfield(app.vertPanel, 'numeric');
            app.TT0EditField.Value = 3;
            app.Label_26 = uilabel(app.vertPanel);
            app.Label_26.Text = '阻尼比：zeta';
            app.vertZetaEditField = uieditfield(app.vertPanel, 'numeric');
            app.vertZetaEditField.Value = 0.2;
            app.solvePanel = uipanel(app.responseCompute);
            app.solvePanel.Title = '求解';
            app.sEditFieldLabel_4 = uilabel(app.solvePanel);
            app.sEditFieldLabel_4.Text = '积分步长(s)：';
            app.stepEditField = uieditfield(app.solvePanel, 'numeric');
            app.stepEditField.Value = 0.02;
            app.PGAgEditFieldLabel = uilabel(app.solvePanel);
            app.PGAgEditFieldLabel.Text = 'PGA(g)：';
            app.PGAEditField = uieditfield(app.solvePanel, 'numeric');
            app.PGAEditField.Value = 0.4;
            app.computeMaxButton = uibutton(app.solvePanel, 'push');
            app.computeMaxButton.ButtonPushedFcn = createCallbackFcn(app, @computeMaxButtonPushed, true);
            app.computeMaxButton.Text = '计算最大响应';
            app.resultPanel = uipanel(app.responseCompute);
            app.resultPanel.Title = '结果';
            app.mmLabel = uilabel(app.resultPanel);
            app.mmLabel.Text = '水平隔震层最大位移(mm)：';
            app.horiEditField = uieditfield(app.resultPanel, 'numeric');
            app.horiEditField.Editable = 'off';
            app.mmLabel_2 = uilabel(app.resultPanel);
            app.mmLabel_2.Text = '竖向隔震层最大位移(mm)：';
            app.vertEditField = uieditfield(app.resultPanel, 'numeric');
            app.vertEditField.ValueChangedFcn = createCallbackFcn(app, @vertEditFieldValueChanged, true);
            app.vertEditField.Editable = 'off';
            app.Label_21 = uilabel(app.responseCompute);
            app.Label_21.Text = '水平隔震层位移限值(mm)';
            app.horiLimitEditField = uieditfield(app.responseCompute, 'numeric');
            app.horiLimitEditField.Editable = 'off';
            app.vertSupportDesign = uitab(app.TabGroup);
            app.vertSupportDesign.Title = '竖向支座设计';
            app.Image2 = uiimage(app.vertSupportDesign);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'spring.svg');
            app.BCPanel = uipanel(app.vertSupportDesign);
            app.BCPanel.Title = '边界设置';
            app.dminEditFieldLabel = uilabel(app.BCPanel);
            app.dminEditFieldLabel.Text = 'dmin';
            app.dminEditField = uieditfield(app.BCPanel, 'numeric');
            app.dminEditField.Limits = [0 Inf];
            app.dminEditField.ValueChangedFcn = createCallbackFcn(app, @dminEditFieldValueChanged, true);
            app.dminEditField.Value = 1;
            app.dmaxEditFieldLabel = uilabel(app.BCPanel);
            app.dmaxEditFieldLabel.Text = 'dmax';
            app.dmaxEditField = uieditfield(app.BCPanel, 'numeric');
            app.dmaxEditField.Limits = [0 Inf];
            app.dmaxEditField.Value = Inf;
            app.DminEditFieldLabel = uilabel(app.BCPanel);
            app.DminEditFieldLabel.Text = 'Dmin';
            app.DminEditField = uieditfield(app.BCPanel, 'numeric');
            app.DminEditField.Limits = [0 Inf];
            app.DminEditField.Value = 1;
            app.DmaxEditFieldLabel = uilabel(app.BCPanel);
            app.DmaxEditFieldLabel.Text = 'Dmax';
            app.DmaxEditField = uieditfield(app.BCPanel, 'numeric');
            app.DmaxEditField.Limits = [0 Inf];
            app.DmaxEditField.Value = Inf;
            app.fbminEditFieldLabel = uilabel(app.BCPanel);
            app.fbminEditFieldLabel.Text = 'fbmin';
            app.fbminEditField = uieditfield(app.BCPanel, 'numeric');
            app.fbminEditField.Limits = [0 Inf];
            app.fbminEditField.Value = 1;
            app.fbmaxEditFieldLabel = uilabel(app.BCPanel);
            app.fbmaxEditFieldLabel.Text = 'fbmax';
            app.fbmaxEditField = uieditfield(app.BCPanel, 'numeric');
            app.fbmaxEditField.Limits = [0 Inf];
            app.fbmaxEditField.Value = Inf;
            app.nminEditFieldLabel = uilabel(app.BCPanel);
            app.nminEditFieldLabel.Text = 'nmin';
            app.nminEditField = uieditfield(app.BCPanel, 'numeric');
            app.nminEditField.Limits = [0 Inf];
            app.nminEditField.Value = 1;
            app.nmaxEditFieldLabel = uilabel(app.BCPanel);
            app.nmaxEditFieldLabel.Text = 'nmax';
            app.nmaxEditField = uieditfield(app.BCPanel, 'numeric');
            app.nmaxEditField.Limits = [0 Inf];
            app.nmaxEditField.Value = Inf;
            app.kfnPanel = uipanel(app.vertSupportDesign);
            app.kfnPanel.Title = '设计参数';
            app.mmEditFieldLabel = uilabel(app.kfnPanel);
            app.mmEditFieldLabel.Text = '竖向隔震层最大位移(mm)：';
            app.vertEditField_SD = uieditfield(app.kfnPanel, 'numeric');
            app.vertEditField_SD.Limits = [0 Inf];
            app.vertEditField_SD.ValueDisplayFormat = '%.0f';
            app.vertEditField_SD.ValueChangedFcn = createCallbackFcn(app, @vertEditField_SDValueChanged, true);
            app.mmLabel_3 = uilabel(app.kfnPanel);
            app.mmLabel_3.Text = '修正后最大位移(mm)：';
            app.vertConfixedEditField = uieditfield(app.kfnPanel, 'numeric');
            app.vertConfixedEditField.Limits = [0 Inf];
            app.vertConfixedEditField.ValueDisplayFormat = '%.0f';
            app.vertConfixedEditField.ValueChangedFcn = createCallbackFcn(app, @vertConfixedEditFieldValueChanged, true);
            app.vertConfixedEditField.Tooltip = {''};
            app.sEditFieldLabel_5 = uilabel(app.kfnPanel);
            app.sEditFieldLabel_5.Text = '隔震层竖向周期(s)：';
            app.TEditField = uieditfield(app.kfnPanel, 'numeric');
            app.TEditField.Limits = [0 Inf];
            app.TEditField.ValueChangedFcn = createCallbackFcn(app, @TEditFieldValueChanged, true);
            app.Label_16 = uilabel(app.kfnPanel);
            app.Label_16.Text = '隔震层竖向刚度(N/mm)：';
            app.KvTotalEditField = uieditfield(app.kfnPanel, 'numeric');
            app.KvTotalEditField.Limits = [0 Inf];
            app.KvTotalEditField.ValueDisplayFormat = '%.0f';
            app.Label_17 = uilabel(app.kfnPanel);
            app.Label_17.Text = '支座个数：';
            app.supportNumEditField = uieditfield(app.kfnPanel, 'numeric');
            app.supportNumEditField.Limits = [1 Inf];
            app.supportNumEditField.ValueChangedFcn = createCallbackFcn(app, @supportNumEditFieldValueChanged, true);
            app.supportNumEditField.Value = 1;
            app.NmmEditFieldLabel = uilabel(app.kfnPanel);
            app.NmmEditFieldLabel.Text = '隔震支座竖向刚度(N/mm)：';
            app.KvSingleEditField = uieditfield(app.kfnPanel, 'numeric');
            app.KvSingleEditField.Limits = [0 Inf];
            app.KvSingleEditField.ValueDisplayFormat = '%.0f';
            app.mmLabel_5 = uilabel(app.kfnPanel);
            app.mmLabel_5.Text = '压缩量（含静位移）(mm)：';
            app.fnEditField = uieditfield(app.kfnPanel, 'numeric');
            app.resultPanel_SD = uipanel(app.vertSupportDesign);
            app.resultPanel_SD.Title = '设计结果';
            app.mmLabel_4 = uilabel(app.resultPanel_SD);
            app.mmLabel_4.Text = '材料直径：d(mm)';
            app.dEditField = uieditfield(app.resultPanel_SD, 'numeric');
            app.DmmEditFieldLabel = uilabel(app.resultPanel_SD);
            app.DmmEditFieldLabel.Text = '弹簧中径：D(mm)';
            app.DEditField = uieditfield(app.resultPanel_SD, 'numeric');
            app.nEditFieldLabel = uilabel(app.resultPanel_SD);
            app.nEditFieldLabel.Text = '有效圈数：n';
            app.nEditField = uieditfield(app.resultPanel_SD, 'numeric');
            app.Label_18 = uilabel(app.resultPanel_SD);
            app.Label_18.Text = '节间距：fb(mm)';
            app.fbEditField = uieditfield(app.resultPanel_SD, 'numeric');
            app.H0mmEditFieldLabel = uilabel(app.resultPanel_SD);
            app.H0mmEditFieldLabel.Text = '自由高度：H0(mm)';
            app.H0EditField = uieditfield(app.resultPanel_SD, 'numeric');
            app.designButton = uibutton(app.vertSupportDesign, 'push');
            app.designButton.ButtonPushedFcn = createCallbackFcn(app, @designButtonPushed, true);
            app.designButton.Text = '设计';
            app.optButtonGroup = uibuttongroup(app.vertSupportDesign);
            app.optButtonGroup.Title = '优化目标';
            app.optH0Button = uiradiobutton(app.optButtonGroup);
            app.optH0Button.Text = '自由高度';
            app.optH0Button.Value = true;
            % Show the figure after all components are created
            app.liildUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = liild

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.liildUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.liildUIFigure)
        end
    end
end